classdef ByrdApp < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                      matlab.ui.Figure
		MATButton                     matlab.ui.control.Button
		StyleLabel                    matlab.ui.control.Label
		yvaluesLabel                  matlab.ui.control.Label
		xvaluesLabel                  matlab.ui.control.Label
		ColorButtonGroup              matlab.ui.container.ButtonGroup
		PurpleButton                  matlab.ui.control.RadioButton
		YellowButton                  matlab.ui.control.RadioButton
		GreenButton                   matlab.ui.control.RadioButton
		RedButton                     matlab.ui.control.RadioButton
		BlueButton                    matlab.ui.control.RadioButton
		Label                         matlab.ui.control.Label
		CitationButton                matlab.ui.control.Button
		JPGButton                     matlab.ui.control.Button
		MATLABFigureButton            matlab.ui.control.Button
		SaveasLabel_2                 matlab.ui.control.Label
		SaveasLabel                   matlab.ui.control.Label
		TextButton                    matlab.ui.control.Button
		ExcelButton                   matlab.ui.control.Button
		CSVButton                     matlab.ui.control.Button
		SubsetIDDropDown              matlab.ui.control.DropDown
		SubsetIDDropDownLabel         matlab.ui.control.Label
		DataIDDropDown                matlab.ui.control.DropDown
		DataIDDropDownLabel           matlab.ui.control.Label
		ClearButton                   matlab.ui.control.Button
		SelectIceCoreDropDownLabel_2  matlab.ui.control.Label
		PNGButton                     matlab.ui.control.Button
		PlotButton                    matlab.ui.control.Button
		PlotType                      matlab.ui.control.DropDown
		SelectYData                   matlab.ui.control.DropDown
		SelectXData                   matlab.ui.control.DropDown
		ImportButton                  matlab.ui.control.Button
		UITable                       matlab.ui.control.Table
		DataTableLabel                matlab.ui.control.Label
		TextArea                      matlab.ui.control.TextArea
		ViewAvailableDataSetsLabel    matlab.ui.control.Label
		SelectIceCoreDropDown         matlab.ui.control.DropDown
		SelectIceCoreDropDownLabel    matlab.ui.control.Label
		UIAxes                        matlab.ui.control.UIAxes
	end


	methods (Access = private)

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Value changed function: SelectIceCoreDropDown
		function SelectIceCoreDropDownValueChanged(app, event)
			
			switch app.SelectIceCoreDropDown.Value
				case 'Bona-Churchill'
					core=icecoreStruct("Bona-Churchill");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ss,1);
					finalprint=sprintf('%s\n',repmat('=',30,1));
					for n = 1:sz
						% Data set ID:
							temp1=sprintf('%s\n','Data set ID:');
							temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
						% Subset ID:
							sssz = size(ss{n},1); % Number of subsets in ds
							temp = ss{n}(1:sssz); 
							temp3=sprintf('%s\n','Subset ID:');
							if temp ~= "N/A"
								temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
							else
								temp4=sprintf('%s\n','This data set has no subsets.');
							end
						% Divider
							temp5=sprintf('%s\n',repmat('=',30,1));
						% Add to finalprint
						finalprint=[string(finalprint);...
							string(temp1);...
							string(temp2);...
							string(temp3);...
							string(temp4);...
							string(temp5)];
					end
					concatenatedStrings = strjoin(finalprint, '\n');
					app.TextArea.Value=sprintf(concatenatedStrings);

					% Edit Data Set and Subset Dropdown Menus
					dsID = ["";"bona-churchillBC";"bona-churchill800"];
					app.DataIDDropDown.Items=dsID;

				case 'Bruce Plateau'
					core=icecoreStruct("Bruce Plateau");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

							% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"bruce-plateau"];
							app.DataIDDropDown.Items=dsID;

				case 'Dasuopu'
					core=icecoreStruct("Dasuopu");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ss,1);
					finalprint=sprintf('%s\n',repmat('=',30,1));
					for n = 1:sz
						% Data set ID:
							temp1=sprintf('%s\n','Data set ID:');
							temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
						% Subset ID:
							sssz = size(ss{n},1); % Number of subsets in ds
							temp = ss{n}(1:sssz); 
							temp3=sprintf('%s\n','Subset ID:');
							if temp ~= "N/A"
								temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
							else
								temp4=sprintf('%s\n','This data set has no subsets.');
							end
						% Divider
							temp5=sprintf('%s\n',repmat('=',30,1));
						% Add to finalprint
						finalprint=[string(finalprint);...
							string(temp1);...
							string(temp2);...
							string(temp3);...
							string(temp4);...
							string(temp5)];
					end
					concatenatedStrings = strjoin(finalprint, '\n');
					app.TextArea.Value=sprintf(concatenatedStrings);

					% Edit Data Set and Subset Dropdown Menus
						dsID = ["";"dasuopu1000";"dasuopu500TE";"dunde-guliya-dasuopu"];
						app.DataIDDropDown.Items=dsID;

				case 'Dunde'
					core=icecoreStruct("Dunde");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ss,1);
					finalprint=sprintf('%s\n',repmat('=',30,1));
					for n = 1:sz
						% Data set ID:
							temp1=sprintf('%s\n','Data set ID:');
							temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
						% Subset ID:
							sssz = size(ss{n},1); % Number of subsets in ds
							temp = ss{n}(1:sssz); 
							temp3=sprintf('%s\n','Subset ID:');
							if temp ~= "N/A"
								temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
							else
								temp4=sprintf('%s\n','This data set has no subsets.');
							end
						% Divider
							temp5=sprintf('%s\n',repmat('=',30,1));
						% Add to finalprint
						finalprint=[string(finalprint);...
							string(temp1);...
							string(temp2);...
							string(temp3);...
							string(temp4);...
							string(temp5)];
					end
					concatenatedStrings = strjoin(finalprint, '\n');
					app.TextArea.Value=sprintf(concatenatedStrings);

					% Edit Data Set and Subset Dropdown Menus
						dsID = ["";"dunde-guliya-dasuopu";"dunde1500"];
						app.DataIDDropDown.Items=dsID;

				case 'Guliya'
					core=icecoreStruct("Guliya");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ss,1);
					finalprint=sprintf('%s\n',repmat('=',30,1));
					for n = 1:sz
						% Data set ID:
							temp1=sprintf('%s\n','Data set ID:');
							temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
						% Subset ID:
							sssz = size(ss{n},1); % Number of subsets in ds
							temp = ss{n}(1:sssz); 
							temp3=sprintf('%s\n','Subset ID:');
							if temp ~= "N/A"
								temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
							else
								temp4=sprintf('%s\n','This data set has no subsets.');
							end
						% Divider
							temp5=sprintf('%s\n',repmat('=',30,1));
						% Add to finalprint
						finalprint=[string(finalprint);...
							string(temp1);...
							string(temp2);...
							string(temp3);...
							string(temp4);...
							string(temp5)];
					end
					concatenatedStrings = strjoin(finalprint, '\n');
					app.TextArea.Value=sprintf(concatenatedStrings);

					% Edit Data Set and Subset Dropdown Menus
						dsID = ["";"dunde-guliya-dasuopu";...
							"guliya-thickness";"guliya15ka";...
							"guliya2015";"guliya1992";...
							"guliya340TE";"guliya45TE"];
						app.DataIDDropDown.Items=dsID;

				case 'IPO Index'
					core=icecoreStruct("IPO Index");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
						dsID = ["";"IPO550"];
						app.DataIDDropDown.Items=dsID;

				case 'Kilimanjaro'
					core=icecoreStruct("Kilimanjaro");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"kilimanjaro"];
							app.DataIDDropDown.Items=dsID;

				case 'Nevado Huascaran'
					core=icecoreStruct("Nevado Huascaran");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"huascaran1993"];
							app.DataIDDropDown.Items=dsID;

				case 'Puncak Jaya'
					core=icecoreStruct("Puncak Jaya");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ss,1);
					finalprint=sprintf('%s\n',repmat('=',30,1));
					for n = 1:sz
						% Data set ID:
							temp1=sprintf('%s\n','Data set ID:');
							temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
						% Subset ID:
							sssz = size(ss{n},1); % Number of subsets in ds
							temp = ss{n}(1:sssz); 
							temp3=sprintf('%s\n','Subset ID:');
							if temp ~= "N/A"
								temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
							else
								temp4=sprintf('%s\n','This data set has no subsets.');
							end
						% Divider
							temp5=sprintf('%s\n',repmat('=',30,1));
						% Add to finalprint
						finalprint=[string(finalprint);...
							string(temp1);...
							string(temp2);...
							string(temp3);...
							string(temp4);...
							string(temp5)];
					end
					concatenatedStrings = strjoin(finalprint, '\n');
					app.TextArea.Value=sprintf(concatenatedStrings);

					% Edit Data Set and Subset Dropdown Menus
						dsID = ["";"papua-indonesia";"papua-rainfall"];
						app.DataIDDropDown.Items=dsID;


				case 'Plateau Remote'
					core=icecoreStruct("Plateau Remote");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"plateau-remote"];
							app.DataIDDropDown.Items=dsID;


				case 'Prince of Wales'
					core=icecoreStruct("Prince of Wales");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"prince-of-wales"];
							app.DataIDDropDown.Items=dsID;

				case 'Puruogangri'
					core=icecoreStruct("Puruogangri");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ss,1);
					finalprint=sprintf('%s\n',repmat('=',30,1));
					for n = 1:sz
						% Data set ID:
							temp1=sprintf('%s\n','Data set ID:');
							temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
						% Subset ID:
							sssz = size(ss{n},1); % Number of subsets in ds
							temp = ss{n}(1:sssz); 
							temp3=sprintf('%s\n','Subset ID:');
							if temp ~= "N/A"
								temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
							else
								temp4=sprintf('%s\n','This data set has no subsets.');
							end
						% Divider
							temp5=sprintf('%s\n',repmat('=',30,1));
						% Add to finalprint
						finalprint=[string(finalprint);...
							string(temp1);...
							string(temp2);...
							string(temp3);...
							string(temp4);...
							string(temp5)];
					end
					concatenatedStrings = strjoin(finalprint, '\n');
					app.TextArea.Value=sprintf(concatenatedStrings);

					% Edit Data Set and Subset Dropdown Menus
						dsID = ["";"puruogangri";"puruogangri-TE"];
						app.DataIDDropDown.Items=dsID;


				case 'Quelccaya'
					core=icecoreStruct("Quelccaya");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ss,1);
					finalprint=sprintf('%s\n',repmat('=',30,1));
					for n = 1:sz
						% Data set ID:
							temp1=sprintf('%s\n','Data set ID:');
							temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
						% Subset ID:
							sssz = size(ss{n},1); % Number of subsets in ds
							temp = ss{n}(1:sssz); 
							temp3=sprintf('%s\n','Subset ID:');
							if temp ~= "N/A"
								temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
							else
								temp4=sprintf('%s\n','This data set has no subsets.');
							end
						% Divider
							temp5=sprintf('%s\n',repmat('=',30,1));
						% Add to finalprint
						finalprint=[string(finalprint);...
							string(temp1);...
							string(temp2);...
							string(temp3);...
							string(temp4);...
							string(temp5)];
					end
					concatenatedStrings = strjoin(finalprint, '\n');
					app.TextArea.Value=sprintf(concatenatedStrings);

					% Edit Data Set and Subset Dropdown Menus
						dsID = ["";"quelccaya1983";"quelccaya-TE";"quelccaya2003"];
						app.DataIDDropDown.Items=dsID;


				case 'Sajama'
					core=icecoreStruct("Sajama");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"sajama"];
							app.DataIDDropDown.Items=dsID;

				case 'Siple Station'
					core=icecoreStruct("Siple Station");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"siple-station"];
							app.DataIDDropDown.Items=dsID;


				case 'Windy Dome'
					core=icecoreStruct("Windy Dome");
					ds=core.DatasetID;
					ss=core.SubsetID;
					sz = size(ds,1);
						if iscell(ss)
							finalprint=sprintf('%s\n',repmat('=',30,1));
							for n = 1:sz
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds(n),'"'));
								% Subset ID:
									sssz = size(ss{n},1); % Number of subsets in ds
									temp = ss{n}(1:sssz); 
									temp3=sprintf('%s\n','Subset ID:');
									if temp ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',temp,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
							end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);
						else
							id = ss(:,1);
							finalprint=sprintf('%s\n',repmat('=',30,1));
								% Data set ID:
									temp1=sprintf('%s\n','Data set ID:');
									temp2=sprintf('%s\n',strcat('∙ "',ds,'"'));
								% Subset ID: 
									temp3=sprintf('%s\n','Subset ID:');
									if id ~= "N/A"
										temp4=sprintf('%s\n',strcat('∙ "',id,'"'));
									else
										temp4=sprintf('%s\n','This data set has no subsets.');
									end
								% Divider
									temp5=sprintf('%s\n',repmat('=',30,1));
								% Add to finalprint
								finalprint=[string(finalprint);...
									string(temp1);...
									string(temp2);...
									string(temp3);...
									string(temp4);...
									string(temp5)];
						end
							concatenatedStrings = strjoin(finalprint, '\n');
							app.TextArea.Value=sprintf(concatenatedStrings);

						% Edit Data Set and Subset Dropdown Menus
							dsID = ["";"windy-dome"];
							app.DataIDDropDown.Items=dsID;


				otherwise
					app.TextArea.Value="";
			end

		end

		% Value changed function: DataIDDropDown
		function DataIDDropDownValueChanged(app, event)
			value = app.DataIDDropDown.Value;
			
			% If ChooseDataSetDropValue is "Default"
				if value == "Default"
					% Do nothing

			% If value is blank
				elseif value == ""
					app.SubsetIDDropDown.Items = "";

			% BONA-CHURCHILL
				elseif value == "bona-churchillBC"
					items = ["ammonium";"ammonium_annual";"blackcarbon";"blackcarbon_annual"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "bona-churchill800"
					app.SubsetIDDropDown.Items = "";

			% BRUCE PLATEAU
				elseif value == "bruce-plateau"
					items = ["accumulation";"d18O"];
					app.SubsetIDDropDown.Items = items;
			
			% DASUOPU
				elseif value == "dasuopu1000"
					items = ["annual";"decadal";"net-balance"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "dasuopu500TE"
					items = ["TEflux";"EnrichFactor";"TEsubannual"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "dunde-guliya-dasuopu" % Also works for Dunde and Guliya
					app.SubsetIDDropDown.Items = "";

			% DUNDE
				elseif value == "dunde1500"
					items = ["accumulation","d18O","dust-anions"];
					app.SubsetIDDropDown.Items = items;

			% GULIYA
				elseif value == "guliya-thickness"
					items = ["plateau";"summit"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "guliya15ka"
					items = ["raw";"century-average"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "guliya2015"
					items = ["raw-core1";"raw-core2";"annual-core1";"annual-core2"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "guliya1992"
					items = ["raw-data";"decade-average";"400yr-average";"chlorine-36"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "guliya340TE"
					app.SubsetIDDropDown.Items = "";
				elseif value == "guliya45TE"
					app.SubsetIDDropDown.Items = "";

			% IPO INDEX
				elseif value == "IPO550"
					app.SubsetIDDropDown.Items = "";

			% KILIMANJARO
				elseif value == "kilimanjaro"
					items = ["d18O-depth";"d18O-decade";"d18O-50Yavg";...
						"NIF2-chlorine-36";"NIF2-raw";"NIF2-ion";"NIF3-ion"];
					app.SubsetIDDropDown.Items = items;

			% NEVADO HUASCARAN
				elseif value == "huascaran1993"
					items = ["C2-100yr-avg";"C2-anion";...
						"annual-avg-cal";"annual-avg-thermal";...
						"C2-raw";"C1-alt";"C2-alt";"monthly"];
					app.SubsetIDDropDown.Items = items;

			% PUNCAK JAYA
				elseif value == "papua-indonesia"
					items = ["D1-raw";"D1-tritium";"D1B-chemistry";...
						"D1B-raw";"NINO3sst";"log-transform";...
						"tritium-precip"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "papua-rainfall"
					items = ["grasberg";"kuala-kencana";"portsite";...
						"tembagapura";"timika"];
					app.SubsetIDDropDown.Items = items;

			% PLATEAU REMOTE
				elseif value == "plateau-remote"
					items = ["d18O-2ka";"d18O-4ka"];
					app.SubsetIDDropDown.Items = items;

			% PRINCE OF WALES
				elseif value == "prince-of-wales"
					app.SubsetIDDropDown.Items = "";

			% PURUOGANGRI
				elseif value == "puruogangri"
					items = ["C1-d18O-raw";"C1-d18O-decade";"C2-d18O-ions";...
						"C2-5yr-avg";"C2-decade";"C2-100yr-avg"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "puruogangri-TE"
					items = ["raw";"5yr-avg"];
					app.SubsetIDDropDown.Items = items;

			% QUELCCAYA
				elseif value == "quelccaya1983"
					items = ["QC1","QS1","conductivity"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "quelccaya-TE"
					items = ["5yr-avg";"5yr-median";"EF-seasonal"];
					app.SubsetIDDropDown.Items = items;
				elseif value == "quelccaya2003"
					items = ["QND";"QSD-annual";"QSD-decade";"QSD-ALT";"NINO4sst"];
					app.SubsetIDDropDown.Items = items;

			% SAJAMA
				elseif value == "sajama"
					items = ["C1-100yr-avg";"C1-accumulation";"sample-avg"];
					app.SubsetIDDropDown.Items = items;

			% SIPLE STATION
				elseif value == "siple-station"
					app.SubsetIDDropDown.Items = "";

			% WINDY DOME
				elseif value == "windy-dome"
					app.SubsetIDDropDown.Items = "";

			% If value is somehow not defined (I don't think this is
			% possible, but just in case...)
			else
				errordlg("Something went wrong. Subset ID must be a string.","ERROR")
			end

		end

		% Button pushed function: ImportButton
		function ImportButtonPushed(app, event)
			
			% Check value of Data ID drop-down menu
				dataID = string(app.DataIDDropDown.Value);
			% Check value of Subset ID drop-down menu
				subsetID = string(app.SubsetIDDropDown.Value);

			% If dataID does not exist
			if dataID == "" || dataID == "Default"
				errordlg("Data ID cannot be set as 'Default' or blank.","ERROR")

			% If there is a subsetID
			elseif isstring(subsetID) && subsetID ~= ""
				data = readICP(dataID,subsetID);
				varnames = data.Properties.VariableNames;
				app.UITable.Data = data;
				app.UITable.ColumnName = varnames;
				app.DataTableLabel.Text = dataID;

				% Edit plotting drop-down menus
					app.SelectXData.Items = varnames;
					app.SelectYData.Items = varnames;

			% If there is no subsetID
			elseif isstring(subsetID) && subsetID == ""
				data = readICP(dataID);
				varnames = data.Properties.VariableNames;
				app.UITable.Data = data;
				app.UITable.ColumnName = varnames;
				app.DataTableLabel.Text = dataID;

				% Edit plotting drop-down menus
					app.SelectXData.Items = varnames;
					app.SelectYData.Items = varnames;


			% If subsetID is set to 'Default'
			elseif subsetID == "Default"
				errordlg("Subset ID cannot be set to 'Default'.","ERROR")

			
			% If subsetID is somehow undefined (should not be possible, but
			% just in case...)
			else
				errordlg("Something went wrong. Subset ID must be a string","ERROR")
			end

		end

		% Button pushed function: CitationButton
		function CitationButtonPushed(app, event)
			
			% If DataID drop-down is empty
			if app.DataIDDropDown.Value == "" || app.DataIDDropDown.Value == "Default"
				msgbox("Please select an ice core and choose a data ID from the drop-down menu.","Help");

			% If DataID drop-down is not empty
			else
				data_set_ID = string(app.DataIDDropDown.Value);
				cite(data_set_ID)
				msgbox('Citation information printed successfully. Check your MATLAB command window.','Help')

			end

		end

		% Button pushed function: MATButton
		function MATButtonPushed(app, event)
			% Create file name
			dataIDname=app.DataIDDropDown.Value;
			subsetIDname=app.SubsetIDDropDown.Value;
			extension='.mat';
			% Define table as a variable for saving
			data=app.UITable.Data;

			% Save the data
			if subsetIDname == ""
				file_name=strcat(dataIDname,extension);
				save(file_name,"data");
			else
				% Add a connector between DatasetID and SubsetID
				connector='_';
				file_name=strcat(dataIDname,connector,subsetIDname,extension);
				save(file_name,"data");
			end

			% Let user know that the table has been saved successfully
			msgbox('MATLAB binary (.mat) file saved successfully in your current folder.')

		end

		% Button pushed function: TextButton
		function TextButtonPushed(app, event)
			% Create file name
			dataIDname=app.DataIDDropDown.Value;
			subsetIDname=app.SubsetIDDropDown.Value;
			extension='.txt';
			% Define table as a variable for saving
			data=app.UITable.Data;

			% Save the data
			if subsetIDname == ""
				file_name=strcat(dataIDname,extension);
				save(file_name,"data");
			else
				% Add a connector between DatasetID and SubsetID
				connector='_';
				file_name=strcat(dataIDname,connector,subsetIDname,extension);
				save(file_name,"data");
			end

			% Let user know that the table has been saved successfully
			msgbox('Text file saved successfully in your current MATLAB folder.')

		end

		% Button pushed function: CSVButton
		function CSVButtonPushed(app, event)
			% Create file name
			dataIDname=app.DataIDDropDown.Value;
			subsetIDname=app.SubsetIDDropDown.Value;
			extension='.csv';
			% Define table as a variable for saving
			data=app.UITable.Data;

			% Save the data
			if subsetIDname == ""
				file_name=strcat(dataIDname,extension);
				save(file_name,"data");
			else
				% Add a connector between DatasetID and SubsetID
				connector='_';
				file_name=strcat(dataIDname,connector,subsetIDname,extension);
				save(file_name,"data");
			end

			% Let user know that the table has been saved successfully
			msgbox('CSV file saved successfully in your current MATLAB folder.')

		end

		% Button pushed function: ExcelButton
		function ExcelButtonPushed(app, event)
			% Create file name
			dataIDname=app.DataIDDropDown.Value;
			subsetIDname=app.SubsetIDDropDown.Value;
			extension='.xlsx';
			% Define table as a variable for saving
			data=app.UITable.Data;

			% Save the data
			if subsetIDname == ""
				file_name=strcat(dataIDname,extension);
				save(file_name,"data");
			else
				% Add a connector between DatasetID and SubsetID
				connector='_';
				file_name=strcat(dataIDname,connector,subsetIDname,extension);
				save(file_name,"data");
			end

			% Let user know that the table has been saved successfully
			msgbox('Excel file saved successfully in your current MATLAB folder.')

		end

		% Button pushed function: PlotButton
		function PlotButtonPushed(app, event)
			
			%
			% PLOT SELECTED DATA
			%
			% Color (colo)...........% Darker variant (cont)
			if app.BlueButton.Value
				colo='#55d5ff'; cont='#08c2ff';
			elseif app.RedButton.Value
				colo='#ff5580'; cont='#ff225a';
			elseif app.GreenButton.Value
				colo='#55ff7f'; cont='#08ff45';
			elseif app.YellowButton.Value
				colo='#ffd455'; cont='#ffc108';
			else
				colo='#7f55ff'; cont='#5922ff';
			end
			
			% Get data values
			X = app.SelectXData.Value;
			Y = app.SelectYData.Value;
			plt_name = app.PlotType.Value;
			% Get variable names
			varnames = app.UITable.ColumnName;
			varnames = string(varnames);
			x_idx = varnames == X;
			y_idx = varnames == Y;
			% Get variable units
			dataRef = app.UITable.Data;
			varunits = dataRef.Properties.VariableUnits;
			x_units = varunits(x_idx);
			y_units = varunits(y_idx);
			% Get x and y data
			tableData = app.UITable.Data;
			xData = table2array(tableData(:,x_idx));
			yData = table2array(tableData(:,y_idx));
			% Get xmin and xmax
			if iscell(xData) % true for Puncak Jaya monthly SST data
				xData = datetime(cell2mat(xData),'InputFormat','MM-yyyy');
				xMin = min(xData);
				xMax = max(xData);
			else
				xMin = min(xData);
				xMax = max(xData);
			end
			% Change title
			app.UIAxes.Title.String=app.SelectYData.Value;
			app.UIAxes.Title.Interpreter="none";
			app.UIAxes.Title.Color='#ffffff';
			

			if plt_name == "Type"
				plot(app.UIAxes,0:0.1:1,NaN(1,11));
				xlabel(app.UIAxes,'X');
				ylabel(app.UIAxes,'Y');

			elseif plt_name == "Bar"
				% Check if there are duplicates in xData (if so, then
				% remove them so that bar() does not produce an error.
				if numel(xData) ~= numel(unique(xData))
					[~,idx]=unique(xData,'stable');
					toDelete=setdiff(1:numel(xData),idx);
					xData(toDelete)=[];
					yData(toDelete)=[];
				end

				bar(app.UIAxes,xData,yData,...
					'FaceColor',colo,...
					'EdgeColor',cont,...
					'LineWidth',0.3,...
					'FaceAlpha',0.7);
				xlabel(app.UIAxes,x_units);
				ylabel(app.UIAxes,y_units);
				xlim(app.UIAxes,[xMin,xMax]);

			elseif plt_name == "Line"
				line(app.UIAxes,xData,yData,...
					'LineWidth',1.5,...
					'Color',colo);
				xlabel(app.UIAxes,x_units);
				ylabel(app.UIAxes,y_units);
				xlim(app.UIAxes,[xMin,xMax]);

			elseif plt_name == "Scatter"
				scatter(app.UIAxes,xData,yData,...
					24,...
					'Marker','o',...
					'MarkerEdgeColor',cont,...
					'LineWidth',1,...
					'MarkerFaceColor',colo,...
					'MarkerFaceAlpha',0.7);
				xlabel(app.UIAxes,x_units);
				ylabel(app.UIAxes,y_units);
				xlim(app.UIAxes,[xMin,xMax]);

			else % plt_name == "Stairs"
				stairs(app.UIAxes,xData,yData,...
					'LineWidth',1.5,...
					'Color',colo);		
				xlabel(app.UIAxes,x_units);
				ylabel(app.UIAxes,y_units);
				xlim(app.UIAxes,[xMin,xMax]);

			end

		end

		% Button pushed function: ClearButton
		function ClearButtonPushed(app, event)
			
			% CLEAR UIAXES
			cla(app.UIAxes)

		end

		% Button pushed function: JPGButton
		function JPGButtonPushed(app, event)
			
			%
			% SAVE FIGURE AS .jpg FILE
			%
			fig_handle = app.UIAxes;
			% Change axes colors for visibility
			fig_handle.XColor = '#234058';
			fig_handle.YColor = '#234058';
			fig_handle.Title.Color = '#234058';

			% Create file name
			dataIDname=app.DataIDDropDown.Value;
			subsetIDname=app.SubsetIDDropDown.Value;
			extension='.jpg';

			% Save the data
			if subsetIDname == ""
				file_name=strcat(dataIDname,extension);
				exportgraphics(fig_handle, file_name, 'Resolution', 300);
			else
				% Add a connector between DatasetID and SubsetID
				connector='_';
				file_name=strcat(dataIDname,connector,subsetIDname,extension);
				exportgraphics(fig_handle, file_name, 'Resolution', 300);
			end

			% Let user know that figure was saved successfully
			msgbox('JPG file saved successfully in your current MATLAB folder.','Helpful Tip')

			% Return axes colors to white
			fig_handle.XColor = '#ffffff';
			fig_handle.YColor = '#ffffff';

		end

		% Button pushed function: PNGButton
		function PNGButtonPushed(app, event)
			
			%
			% SAVE FIGURE AS .png FILE
			%
			fig_handle = app.UIAxes;
			% Change axes colors for visibility
			fig_handle.XColor = '#234058';
			fig_handle.YColor = '#234058';
			fig_handle.Title.Color = '#234058';
			
			% Create file name
			dataIDname=app.DataIDDropDown.Value;
			subsetIDname=app.SubsetIDDropDown.Value;
			extension='.png';

			% Save the data
			if subsetIDname == ""
				file_name=strcat(dataIDname,extension);
				exportgraphics(fig_handle, file_name, 'Resolution', 300);
			else
				% Add a connector between DatasetID and SubsetID
				connector='_';
				file_name=strcat(dataIDname,connector,subsetIDname,extension);
				exportgraphics(fig_handle, file_name, 'Resolution', 300);
			end

			% Let user know that figure was saved successfully
			msgbox('PNG file saved successfully in your current MATLAB folder.','Helpful Tip')

			% Return axes colors to white
			fig_handle.XColor = '#ffffff';
			fig_handle.YColor = '#ffffff';

		end

		% Button pushed function: MATLABFigureButton
		function MATLABFigureButtonPushed(app, event)
			
			% Create a temporary figure with axes.
			fig = figure;
			fig.Visible = 'off';
			figAxes = axes(fig);
			% Copy all UIAxes children, take over axes limits and aspect ratio.            
			allChildren = app.UIAxes.XAxis.Parent.Children;
			copyobj(allChildren, figAxes)
			figAxes.XLim = app.UIAxes.XLim;
			figAxes.YLim = app.UIAxes.YLim;
			figAxes.ZLim = app.UIAxes.ZLim;
			figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
			% The code in the function above is based on answers
			% from MATLAB users David & Joost at:
			% https://www.mathworks.com/matlabcentral/answers/281318-how-can-i-save-a-figure-within-app-designer
			
			% Create file name
			dataIDname=app.DataIDDropDown.Value;
			subsetIDname=app.SubsetIDDropDown.Value;
			extension='.fig';

			% Save the data
			if subsetIDname == ""
				file_name=strcat(dataIDname,extension);
				savefig(fig, file_name);
			else
				% Add a connector between DatasetID and SubsetID
				connector='_';
				file_name=strcat(dataIDname,connector,subsetIDname,extension);
				savefig(fig, file_name);
			end

			% Delete the temporary figure.
			delete(fig);

			% Explain to user how to open file
			msgbox('Figure saved successfully in your current MATLAB folder. To open the figure, type the following into the command window: openfig("file_name.fig","visible")','Helpful Tip')


		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIAxes = uiaxes(app.UIFigure);
			title(app.UIAxes, 'Plot')
			xlabel(app.UIAxes, 'XData')
			ylabel(app.UIAxes, 'YData')
			zlabel(app.UIAxes, 'Z')
			app.SelectIceCoreDropDownLabel = uilabel(app.UIFigure);
			app.SelectIceCoreDropDownLabel.Text = 'Select Ice Core';
			app.SelectIceCoreDropDown = uidropdown(app.UIFigure);
			app.SelectIceCoreDropDown.Items = {'None', 'Bona-Churchill', 'Bruce Plateau', 'Dasuopu', 'Dunde', 'Guliya', 'IPO Index', 'Kilimanjaro', 'Nevado Huascaran', 'Puncak Jaya', 'Plateau Remote', 'Prince of Wales', 'Puruogangri', 'Quelccaya', 'Sajama', 'Siple Station', 'Windy Dome'};
			app.SelectIceCoreDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectIceCoreDropDownValueChanged, true);
			app.SelectIceCoreDropDown.Value = 'None';
			app.ViewAvailableDataSetsLabel = uilabel(app.UIFigure);
			app.ViewAvailableDataSetsLabel.Text = 'View Available Data Sets';
			app.TextArea = uitextarea(app.UIFigure);
			app.DataTableLabel = uilabel(app.UIFigure);
			app.DataTableLabel.Text = 'Data Table';
			app.UITable = uitable(app.UIFigure);
			app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
			app.UITable.RowName = {};
			app.ImportButton = uibutton(app.UIFigure, 'push');
			app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
			app.ImportButton.Text = 'Import';
			app.SelectXData = uidropdown(app.UIFigure);
			app.SelectXData.Items = {'X-Data'};
			app.SelectXData.Value = 'X-Data';
			app.SelectYData = uidropdown(app.UIFigure);
			app.SelectYData.Items = {'Y-Data'};
			app.SelectYData.Value = 'Y-Data';
			app.PlotType = uidropdown(app.UIFigure);
			app.PlotType.Items = {'Type', 'Bar', 'Line', 'Scatter', 'Stairs'};
			app.PlotType.Value = 'Type';
			app.PlotButton = uibutton(app.UIFigure, 'push');
			app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
			app.PlotButton.Text = 'Plot';
			app.PNGButton = uibutton(app.UIFigure, 'push');
			app.PNGButton.ButtonPushedFcn = createCallbackFcn(app, @PNGButtonPushed, true);
			app.PNGButton.Text = 'PNG';
			app.SelectIceCoreDropDownLabel_2 = uilabel(app.UIFigure);
			app.SelectIceCoreDropDownLabel_2.Text = 'Select Parameters';
			app.ClearButton = uibutton(app.UIFigure, 'push');
			app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
			app.ClearButton.Text = 'Clear';
			app.DataIDDropDownLabel = uilabel(app.UIFigure);
			app.DataIDDropDownLabel.Text = 'Data ID';
			app.DataIDDropDown = uidropdown(app.UIFigure);
			app.DataIDDropDown.Items = {'Default'};
			app.DataIDDropDown.ValueChangedFcn = createCallbackFcn(app, @DataIDDropDownValueChanged, true);
			app.DataIDDropDown.Value = 'Default';
			app.SubsetIDDropDownLabel = uilabel(app.UIFigure);
			app.SubsetIDDropDownLabel.Text = 'Subset ID';
			app.SubsetIDDropDown = uidropdown(app.UIFigure);
			app.SubsetIDDropDown.Items = {'Default'};
			app.SubsetIDDropDown.Value = 'Default';
			app.CSVButton = uibutton(app.UIFigure, 'push');
			app.CSVButton.ButtonPushedFcn = createCallbackFcn(app, @CSVButtonPushed, true);
			app.CSVButton.Text = 'CSV';
			app.ExcelButton = uibutton(app.UIFigure, 'push');
			app.ExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelButtonPushed, true);
			app.ExcelButton.Text = 'Excel';
			app.TextButton = uibutton(app.UIFigure, 'push');
			app.TextButton.ButtonPushedFcn = createCallbackFcn(app, @TextButtonPushed, true);
			app.TextButton.Text = 'Text';
			app.SaveasLabel = uilabel(app.UIFigure);
			app.SaveasLabel.Text = 'Save as...';
			app.SaveasLabel_2 = uilabel(app.UIFigure);
			app.SaveasLabel_2.Text = 'Save as...';
			app.MATLABFigureButton = uibutton(app.UIFigure, 'push');
			app.MATLABFigureButton.ButtonPushedFcn = createCallbackFcn(app, @MATLABFigureButtonPushed, true);
			app.MATLABFigureButton.Text = 'MATLAB Figure';
			app.JPGButton = uibutton(app.UIFigure, 'push');
			app.JPGButton.ButtonPushedFcn = createCallbackFcn(app, @JPGButtonPushed, true);
			app.JPGButton.Text = 'JPG';
			app.CitationButton = uibutton(app.UIFigure, 'push');
			app.CitationButton.ButtonPushedFcn = createCallbackFcn(app, @CitationButtonPushed, true);
			app.CitationButton.Text = 'Citation';
			app.Label = uilabel(app.UIFigure);
			app.Label.Text = '__________';
			app.ColorButtonGroup = uibuttongroup(app.UIFigure);
			app.ColorButtonGroup.Title = 'Color';
			app.BlueButton = uiradiobutton(app.ColorButtonGroup);
			app.BlueButton.Text = 'Blue';
			app.BlueButton.Value = true;
			app.RedButton = uiradiobutton(app.ColorButtonGroup);
			app.RedButton.Text = 'Red';
			app.GreenButton = uiradiobutton(app.ColorButtonGroup);
			app.GreenButton.Text = 'Green';
			app.YellowButton = uiradiobutton(app.ColorButtonGroup);
			app.YellowButton.Text = 'Yellow';
			app.PurpleButton = uiradiobutton(app.ColorButtonGroup);
			app.PurpleButton.Text = 'Purple';
			app.xvaluesLabel = uilabel(app.UIFigure);
			app.xvaluesLabel.Text = 'x-values';
			app.yvaluesLabel = uilabel(app.UIFigure);
			app.yvaluesLabel.Text = 'y-values';
			app.StyleLabel = uilabel(app.UIFigure);
			app.StyleLabel.Text = 'Style';
			app.MATButton = uibutton(app.UIFigure, 'push');
			app.MATButton.ButtonPushedFcn = createCallbackFcn(app, @MATButtonPushed, true);
			app.MATButton.Text = 'MAT';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = ByrdApp

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end