classdef Byrd_ICP_Data < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                    matlab.ui.Figure
		ExportCitationButton        matlab.ui.control.Button
		ExportFigureButton          matlab.ui.control.Button
		ExportDataButton            matlab.ui.control.Button
		YDataDropDown               matlab.ui.control.DropDown
		YDataDropDownLabel          matlab.ui.control.Label
		XDataDropDown               matlab.ui.control.DropDown
		XDataDropDownLabel          matlab.ui.control.Label
		OriginalReferenceCitationandDatasetDOILabel  matlab.ui.control.Label
		TextArea                    matlab.ui.control.TextArea
		DataPreviewFigureLabel      matlab.ui.control.Label
		DataPreviewTableLabel       matlab.ui.control.Label
		SelectSubsetDropDown        matlab.ui.control.DropDown
		SelectSubsetDropDownLabel   matlab.ui.control.Label
		SelectDatasetDropDown       matlab.ui.control.DropDown
		SelectDatasetDropDownLabel  matlab.ui.control.Label
		UITable                     matlab.ui.control.Table
		UIAxes                      matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Callback function
		function SelectSubsetDropDownValueChanged(app, event)
			% Get the value selected in the first drop-down menu
    		selectedValue = app.SelectDatasetDropDown.Value;
    		
    		% Define the options for the second drop-down menu based on the selected value
    		switch selectedValue
        		case 'Bona Churchill BC'
            		options = {'None', 'Ammonium', 'Ammonium (annual avg)', 'Black Carbon', 'Black Carbon (annual avg)'};
        		case 'Bona Churchill 800'
            		options = {'None', 'All'};
				case 'Bruce Plateau'
            		options = {'None', 'Accumulation', 'd18O'};
				case 'Dasuopu 1000'
					options = {'None', 'Core 3 Annual', 'Core 3 Decadal', 'Cores 2+3 Annual Accumulation'};
				case 'Dasuopu 500TE'
					options = {};
				case 'Dunde-Guliya-Dasuopu'
					options = {};
				case 'Dunde 1500'
					options = {};
				case 'Guliya Thickness'
					options = {};
				case 'Guliya 15ka'
					options = {};
				case 'Guliya 2015'
					options = {};
				case 'Guliya 1992'
					options = {};
				case 'Guliya 340TE'
					options = {};
				case 'Guliya 45TE'
					options = {};
				case 'IPO550'
					options = {};
				case 'Kilimanjaro'
					options = {};
				case 'Huascaran 1993'
					options = {};
				case 'Papua Indonesia'
					options = {};
				case 'Papua Rainfall'
					options = {};
				case 'Plateau Remote'
					options = {};
				case 'Prince of Wales'
					options = {};
				case 'Puruogangri'
					options = {};
				case 'Puruogangri TE'
					options = {};
				case 'Quelccaya 1983'
					options = {};
				case 'Quelccaya TE'
					options = {};
				case 'Quelccaya 2003'
					options = {};
				case 'Sajama'
					options = {};
				case 'Siple Station'
					options = {};
				case 'Windy Dome'
					options = {};
        		otherwise
            		options = {}; % Empty options if no valid selection
    		end
    		
    		% Update the options in the second drop-down menu
    		app.SelectSubsetDropDown.Items = options;
			
		end

		% Value changed function: SelectDatasetDropDown
		function SelectDatasetDropDownValueChanged(app, event)
			% Call the value changed function for the second drop-down menu
			SelectSubsetDropDownValueChanged(app, event);
			
		end

		% Selection changed function: UITable
		function UITableSelectionChanged(app, event)
			selection = app.UITable.Selection;
			if app.SelectDatasetDropDown.Value=='Bona Churchill BC' && app.SelectSubsetDropDown.Value=='Ammonium'
				data_table=readICP("bona-churchillBC","ammonium");
				app.UITable.DisplayData=data_table;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIAxes = uiaxes(app.UIFigure);
			xlabel(app.UIAxes, 'XData')
			ylabel(app.UIAxes, 'YData')
			zlabel(app.UIAxes, 'Z')
			app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
			app.UITable = uitable(app.UIFigure);
			app.UITable.ColumnName = {'Depth'; 'Isotopes'; 'Nitrate'; 'Dust'};
			app.UITable.RowName = {};
			app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
			app.SelectDatasetDropDownLabel = uilabel(app.UIFigure);
			app.SelectDatasetDropDownLabel.Text = 'Select Dataset';
			app.SelectDatasetDropDown = uidropdown(app.UIFigure);
			app.SelectDatasetDropDown.Items = {'None', 'Bona Churchill BC', 'Bona Churchill 800', 'Bruce Plateau', 'Dasuopu 1000', 'Dasuopu 500TE', 'Dunde-Guliya-Dasuopu', 'Guliya Thickness', 'Guliya 15ka', 'Guliya1992', 'Guliya 340TE', 'Guliya 45TE', 'IPO550', 'Kilimanjaro', 'Huascaran 1993', 'Papua Indonesia', 'Papua Rainfall', 'Plateau Remote', 'Prince of Wales', 'Puruogangri', 'Puruogangri TE', 'Quelccaya 1983', 'Quelccaya TE', 'Quelccaya 2003', 'Sajama', 'Siple Station', 'Windy Dome'};
			app.SelectDatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDatasetDropDownValueChanged, true);
			app.SelectDatasetDropDown.Value = 'None';
			app.SelectSubsetDropDownLabel = uilabel(app.UIFigure);
			app.SelectSubsetDropDownLabel.Text = 'Select Subset';
			app.SelectSubsetDropDown = uidropdown(app.UIFigure);
			app.SelectSubsetDropDown.Items = {'None'};
			app.SelectSubsetDropDown.Value = 'None';
			app.DataPreviewTableLabel = uilabel(app.UIFigure);
			app.DataPreviewTableLabel.Text = 'Data Preview Table';
			app.DataPreviewFigureLabel = uilabel(app.UIFigure);
			app.DataPreviewFigureLabel.Text = 'Data Preview Figure';
			app.TextArea = uitextarea(app.UIFigure);
			app.OriginalReferenceCitationandDatasetDOILabel = uilabel(app.UIFigure);
			app.OriginalReferenceCitationandDatasetDOILabel.Text = 'Original Reference Citation and Dataset DOI';
			app.XDataDropDownLabel = uilabel(app.UIFigure);
			app.XDataDropDownLabel.Text = 'XData';
			app.XDataDropDown = uidropdown(app.UIFigure);
			app.YDataDropDownLabel = uilabel(app.UIFigure);
			app.YDataDropDownLabel.Text = 'YData';
			app.YDataDropDown = uidropdown(app.UIFigure);
			app.ExportDataButton = uibutton(app.UIFigure, 'push');
			app.ExportDataButton.Text = 'Export Data';
			app.ExportFigureButton = uibutton(app.UIFigure, 'push');
			app.ExportFigureButton.Text = 'Export Figure';
			app.ExportCitationButton = uibutton(app.UIFigure, 'push');
			app.ExportCitationButton.Text = 'Export Citation';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = Byrd_ICP_Data

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end